classdef AMloss < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        GridLayout        matlab.ui.container.GridLayout
        LeftPanel         matlab.ui.container.Panel
        AMLabel           matlab.ui.control.Label
        Label_17          matlab.ui.control.Label
        Label_16          matlab.ui.control.Label
        Button            matlab.ui.control.Button
        hub_ratio         matlab.ui.control.EditField
        Label_9           matlab.ui.control.Label
        scr               matlab.ui.control.EditField
        scn               matlab.ui.control.EditField
        Label_8           matlab.ui.control.Label
        tes               matlab.ui.control.EditField
        Label_7           matlab.ui.control.Label
        tc                matlab.ui.control.EditField
        EditField_7Label  matlab.ui.control.Label
        i                 matlab.ui.control.EditField
        beta1             matlab.ui.control.EditField
        Label_6           matlab.ui.control.Label
        alpha2            matlab.ui.control.EditField
        Label_5           matlab.ui.control.Label
        alpha1            matlab.ui.control.EditField
        Label_15          matlab.ui.control.Label
        An2               matlab.ui.control.EditField
        Label_4           matlab.ui.control.Label
        An0               matlab.ui.control.EditField
        Label_3           matlab.ui.control.Label
        B                 matlab.ui.container.ButtonGroup
        B2                matlab.ui.control.RadioButton
        B1                matlab.ui.control.RadioButton
        h                 matlab.ui.control.EditField
        Label_2           matlab.ui.control.Label
        tau               matlab.ui.control.EditField
        Label             matlab.ui.control.Label
        RightPanel        matlab.ui.container.Panel
        title             matlab.ui.control.Label
        Y                 matlab.ui.control.Label
        Ytl               matlab.ui.control.Label
        Ys                matlab.ui.control.Label
        Yp                matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            % 2024/1/16
            % 第二组：AM损失模型计算
            % 界面已经提供了一份参数，可修改其他值
            
            x1 = str2double(app.scn.Value);
            %y1=Y_(p(β_1=0))
            y1 = 0.04989*x1^4-0.13165*x1^3+0.26465*x1^2-0.28628*x1+0.13076;

            x2 = str2double(app.scr.Value);
            %y2=Y_(p(β_1=-α_2))
            y2 = -0.08843*x2^4+0.04836*x2 ^3+0.43407*x2^2-0.559881*x2+0.26896;

            beta1 = str2double(app.beta1.Value);
            alpha2 = str2double(app.alpha2.Value);
            tc = str2double(app.tc.Value);
            %0攻角叶型损失
            Ypi0 = (y1+(((beta1/alpha2))^2)*(y2-y1))*(tc/0.2)^(-beta1/alpha2);

            x3 = 0.75; %x3=sc, sc=0.75
            %出口气流角比值
            y3 = 0.94109*x3^4-3.0081*x3^3+3.3062*x3^2-1.84181*x3+1.48946;
            %s/c=0.75时α2的值
            alpha21 = alpha2/y3;
            x4 = beta1/alpha21;
            %s/c=0.75时失速攻角is的值
            y4 = 2.69892*x4^4-3.61499*x4^3-19.7667*x4^2+10.44443*x4+29.03986;

            x5 = str2double(app.scn.Value);
            %Δis
            y5 = -30.2301*x5^4+54.78919*x5^3-83.57575*x5^2+41.24327*x5+2.3276;

            i = str2double(app.i.Value);
            is = y5+y4;
            x6=i/is;
            %chi为χi攻角系数
            chi = 0.01277*x6^5+0.11216*x6^4+0.36634*x6^3+0.76088*x6^2-0.13382*x6+0.97359;
            %叶型损失系数
            Yp = Ypi0*chi;
            app.Yp.Text = "叶型损失系数：" + Yp;
            
            scn = str2double(app.scn.Value);
            alpha1 = str2double(app.alpha1.Value);
            An0 = str2double(app.An0.Value);
            An2 = str2double(app.An2.Value);
            %涡轮叶栅平均气流角
            alpham = atan((tan(alpha1* pi / 180)+tan(alpha2* pi / 180))/2);
            %叶片升力系数
            CL = scn*2*(tan(alpha1* pi / 180)-tan(alpha2* pi / 180))*cos(alpham);
            A1 = An0*cos(beta1* pi / 180);
            A2 = An2*cos(alpha2* pi / 180);
            
            hub_ratio = str2double(app.hub_ratio.Value);
            if(app.B1.Value)
                B = 0.25;
            else
                B = 0.75;
            end
            tau = str2double(app.tau.Value);
            h = str2double(app.h.Value);
            tes = str2double(app.tes.Value);
            Re = 1.95*10^5;
            %λ函数的横坐标x7
            x7 = (A2/A1)^2/(1+1/hub_ratio);
            %λ=y7
            y7 = -0.27343*x7^4+0.31402*x7^3-0.01607*x7^2+0.00887*x7+0.00522;
            %二次流损失系数
            Ys = 4*y7*(tan(alpha1* pi / 180)-tan(alpha2* pi / 180))^2*(cos(alpha2* pi / 180).^2/cos(alpham));
            app.Ys.Text = "二次流损失系数：" + Ys;
            %叶尖间隙损失系数
            Ytl = B*tau/h*4*(tan(alpha1* pi / 180)-tan(alpha2* pi / 180))^2*(cos(alpha2* pi / 180).^2/cos(alpham));
            app.Ytl.Text = "叶尖间隙损失系数：" + Ytl;
            %x8=te/s;
            x8 = tes;
            %尾缘校正系数χTe
            y8 = 49.1102*x8^4-68.6543*x8^3-29.4434*x8^2+3.7812*x8+0.91028;
            %总损失系数
            Y = ((Yp+Ys)*(Re/2/10^5)+Ytl)*y8;
            app.Y.Text = "总损失系数：" + Y;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {761, 761};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {369, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Label = uilabel(app.LeftPanel);
            app.Label.Text = '叶尖间隙';
            app.tau = uieditfield(app.LeftPanel, 'text');
            app.tau.Value = '0.001';
            app.Label_2 = uilabel(app.LeftPanel);
            app.Label_2.Text = '环面高度';
            app.h = uieditfield(app.LeftPanel, 'text');
            app.h.Value = '0.0512';
            app.B = uibuttongroup(app.LeftPanel);
            app.B.Title = '系数';
            app.B1 = uiradiobutton(app.B);
            app.B1.Text = '带叶冠 0.25';
            app.B1.Value = true;
            app.B2 = uiradiobutton(app.B);
            app.B2.Text = '不带叶冠 0.5';
            app.Label_3 = uilabel(app.LeftPanel);
            app.Label_3.Text = '叶片上游参考平面中的环形面积';
            app.An0 = uieditfield(app.LeftPanel, 'text');
            app.An0.Value = '0.0626';
            app.Label_4 = uilabel(app.LeftPanel);
            app.Label_4.Text = '叶片下游参考平面中的环形面积';
            app.An2 = uieditfield(app.LeftPanel, 'text');
            app.An2.Value = '0.0833';
            app.Label_15 = uilabel(app.LeftPanel);
            app.Label_15.Text = '气流进口角';
            app.alpha1 = uieditfield(app.LeftPanel, 'text');
            app.alpha1.Value = '28';
            app.Label_5 = uilabel(app.LeftPanel);
            app.Label_5.Text = '气流出口角(-65,-55)';
            app.alpha2 = uieditfield(app.LeftPanel, 'text');
            app.alpha2.Value = '-60';
            app.Label_6 = uilabel(app.LeftPanel);
            app.Label_6.Text = '进口几何角';
            app.beta1 = uieditfield(app.LeftPanel, 'text');
            app.beta1.Value = '45';
            app.i = uieditfield(app.LeftPanel, 'text');
            app.i.Value = '6';
            app.EditField_7Label = uilabel(app.LeftPanel);
            app.EditField_7Label.Text = '叶片最大厚度除以弦长';
            app.tc = uieditfield(app.LeftPanel, 'text');
            app.tc.Value = '0.2';
            app.Label_7 = uilabel(app.LeftPanel);
            app.Label_7.Text = '尾缘厚度与叶片栅距的比值';
            app.tes = uieditfield(app.LeftPanel, 'text');
            app.tes.Value = '0.02';
            app.Label_8 = uilabel(app.LeftPanel);
            app.Label_8.Text = '静叶弦长比';
            app.scn = uieditfield(app.LeftPanel, 'text');
            app.scn.Value = '0.86';
            app.scr = uieditfield(app.LeftPanel, 'text');
            app.scr.Value = '0.83';
            app.Label_9 = uilabel(app.LeftPanel);
            app.Label_9.Text = '轮毂比';
            app.hub_ratio = uieditfield(app.LeftPanel, 'text');
            app.hub_ratio.Value = '1.29';
            app.Button = uibutton(app.LeftPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '计算';
            app.Label_16 = uilabel(app.LeftPanel);
            app.Label_16.Text = '动叶弦长比';
            app.Label_17 = uilabel(app.LeftPanel);
            app.Label_17.Text = '攻角';
            app.AMLabel = uilabel(app.LeftPanel);
            app.AMLabel.Text = 'AM损失模型';
            app.RightPanel = uipanel(app.GridLayout);
            app.Yp = uilabel(app.RightPanel);
            app.Yp.Text = '叶型损失系数Yp：';
            app.Ys = uilabel(app.RightPanel);
            app.Ys.Text = '二次流损失系数Ys：';
            app.Ytl = uilabel(app.RightPanel);
            app.Ytl.Text = '叶尖间隙损失系数Ytl：';
            app.Y = uilabel(app.RightPanel);
            app.Y.Text = '总损失系数Y：';
            app.title = uilabel(app.RightPanel);
            app.title.Text = '计算结果：';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AMloss

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end